classdef Midterm_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        fortimevectortstopEditField    matlab.ui.control.NumericEditField
        fortimevectortstopEditFieldLabel  matlab.ui.control.Label
        SignalTypeButtonGroup          matlab.ui.container.ButtonGroup
        discreteButton                 matlab.ui.control.ToggleButton
        continuousButton               matlab.ui.control.ToggleButton
        MidtermbyAzizjon12214751Label  matlab.ui.control.Label
        foromegaHzwforgrowthfactoraEditField  matlab.ui.control.NumericEditField
        foromegaHzwforgrowthfactoraLabel  matlab.ui.control.Label
        fortimevectortstartEditField   matlab.ui.control.NumericEditField
        fortimevectortstartEditFieldLabel  matlab.ui.control.Label
        forAmplitudaAEditField         matlab.ui.control.NumericEditField
        forAmplitudaAEditFieldLabel    matlab.ui.control.Label
        OperationTypeButtonGroup       matlab.ui.container.ButtonGroup
        ReversingButton                matlab.ui.control.ToggleButton
        ScalingButton                  matlab.ui.control.ToggleButton
        ShiftingButton                 matlab.ui.control.ToggleButton
        FunctionTypeButtonGroup        matlab.ui.container.ButtonGroup
        ExponentitiallyDampedSinusoidalfxAsinwtexpatButton  matlab.ui.control.RadioButton
        ExponentialfxAexpatButton      matlab.ui.control.RadioButton
        LogarithmicfunctionfxAlogwtButton  matlab.ui.control.RadioButton
        SinusodialfunctionfxAsinwtButton  matlab.ui.control.RadioButton
        RUNButton                      matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        A;
        t_start;
        t_stop;
        w;
        f_x;
        t;
        usage;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: FunctionTypeButtonGroup
        function FunctionTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.FunctionTypeButtonGroup.SelectedObject;
                if selectedButton == app.SinusodialfunctionfxAsinwtButton
                    app.f_x = app.A*sin(app.w*app.t);
                elseif selectedButton == app.ExponentialfxAexpatButton
                    app.f_x=app.A*exp (app.w*app.t);
                elseif selectedButton == app.ExponentitiallyDampedSinusoidalfxAsinwtexpatButton
                    app.f_x=app.A*sin(app.w*app.t).*exp(app.w*app.t);
                elseif selectedButton == app.LogarithmicfunctionfxAlogwtButton
                   app.f_x = app.A*square(app.w*app.t);
                end

        end

        % Value changed function: forAmplitudaAEditField
        function forAmplitudaAEditFieldValueChanged(app, event)
             app.A = app.forAmplitudaAEditField.Value;
            
        end

        % Value changed function: fortimevectortstartEditField
        function fortimevectortstartEditFieldValueChanged(app, event)
            app.t_start= app.fortimevectortstartEditField.Value;
            
        end

        % Value changed function: fortimevectortstopEditField
        function fortimevectortstopEditFieldValueChanged(app, event)
            app.t_stop = app.fortimevectortstopEditField.Value;
            
        end

        % Value changed function: foromegaHzwforgrowthfactoraEditField
        function foromegaHzwforgrowthfactoraEditFieldValueChanged(app, event)
            app.w = app.foromegaHzwforgrowthfactoraEditField.Value;
            
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            if app.usage == true
               plot(app.UIAxes,  app.f_x);
            elseif app.usage == false
                stem(app.UIAxes,  app.f_x);
            end
        end

        % Selection changed function: SignalTypeButtonGroup
        function SignalTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.SignalTypeButtonGroup.SelectedObject;
            if selectedButton == app.continuousButton
                app.t = linspace(app.t_start, app.t_stop);
                app.usage = true;
            elseif selectedButton == app.discreteButton
                app.t = app.t_start:app.t_stop;
                app.usage = false;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.RUNButton = uibutton(app.UIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.FunctionTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.FunctionTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FunctionTypeButtonGroupSelectionChanged, true);
            app.FunctionTypeButtonGroup.Title = 'Function Type';
            app.SinusodialfunctionfxAsinwtButton = uiradiobutton(app.FunctionTypeButtonGroup);
            app.SinusodialfunctionfxAsinwtButton.Text = 'Sinusodial function f(x)=A*sin(w*t)';
            app.SinusodialfunctionfxAsinwtButton.Value = true;
            app.LogarithmicfunctionfxAlogwtButton = uiradiobutton(app.FunctionTypeButtonGroup);
            app.LogarithmicfunctionfxAlogwtButton.Text = 'Logarithmic function f(x)=A*log(w*t)';
            app.ExponentialfxAexpatButton = uiradiobutton(app.FunctionTypeButtonGroup);
            app.ExponentialfxAexpatButton.Text = 'Exponential f(x)=A*exp (a*t)';
            app.ExponentitiallyDampedSinusoidalfxAsinwtexpatButton = uiradiobutton(app.FunctionTypeButtonGroup);
            app.ExponentitiallyDampedSinusoidalfxAsinwtexpatButton.Text = {'Exponentitially Damped Sinusoidal'; 'f(x)=A*sin(w*t).*exp(a*t) '};
            app.OperationTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.OperationTypeButtonGroup.Title = 'Operation Type';
            app.ShiftingButton = uitogglebutton(app.OperationTypeButtonGroup);
            app.ShiftingButton.Text = 'Shifting';
            app.ShiftingButton.Value = true;
            app.ScalingButton = uitogglebutton(app.OperationTypeButtonGroup);
            app.ScalingButton.Text = 'Scaling';
            app.ReversingButton = uitogglebutton(app.OperationTypeButtonGroup);
            app.ReversingButton.Text = 'Reversing';
            app.forAmplitudaAEditFieldLabel = uilabel(app.UIFigure);
            app.forAmplitudaAEditFieldLabel.Text = 'for Amplituda -A';
            app.forAmplitudaAEditField = uieditfield(app.UIFigure, 'numeric');
            app.forAmplitudaAEditField.ValueChangedFcn = createCallbackFcn(app, @forAmplitudaAEditFieldValueChanged, true);
            app.fortimevectortstartEditFieldLabel = uilabel(app.UIFigure);
            app.fortimevectortstartEditFieldLabel.Text = 'for time vector -t(start)';
            app.fortimevectortstartEditField = uieditfield(app.UIFigure, 'numeric');
            app.fortimevectortstartEditField.ValueChangedFcn = createCallbackFcn(app, @fortimevectortstartEditFieldValueChanged, true);
            app.foromegaHzwforgrowthfactoraLabel = uilabel(app.UIFigure);
            app.foromegaHzwforgrowthfactoraLabel.Text = {'for omega (Hz)-w/ '; 'for growth factor a'};
            app.foromegaHzwforgrowthfactoraEditField = uieditfield(app.UIFigure, 'numeric');
            app.foromegaHzwforgrowthfactoraEditField.ValueChangedFcn = createCallbackFcn(app, @foromegaHzwforgrowthfactoraEditFieldValueChanged, true);
            app.MidtermbyAzizjon12214751Label = uilabel(app.UIFigure);
            app.MidtermbyAzizjon12214751Label.Text = 'Midterm by Azizjon 12214751';
            app.SignalTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.SignalTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SignalTypeButtonGroupSelectionChanged, true);
            app.SignalTypeButtonGroup.Title = 'Signal Type';
            app.continuousButton = uitogglebutton(app.SignalTypeButtonGroup);
            app.continuousButton.Text = 'continuous';
            app.continuousButton.Value = true;
            app.discreteButton = uitogglebutton(app.SignalTypeButtonGroup);
            app.discreteButton.Text = 'discrete';
            app.fortimevectortstopEditFieldLabel = uilabel(app.UIFigure);
            app.fortimevectortstopEditFieldLabel.Text = 'for time vector -t(stop)';
            app.fortimevectortstopEditField = uieditfield(app.UIFigure, 'numeric');
            app.fortimevectortstopEditField.ValueChangedFcn = createCallbackFcn(app, @fortimevectortstopEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Midterm_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end